classdef Osiris < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Importar2Button            matlab.ui.control.Button
        ImportarPlanilhaButton     matlab.ui.control.Button
        PlanTable                  matlab.ui.control.Table
        RTable                     matlab.ui.control.Table
        PlotarcompontoButton       matlab.ui.control.Button
        X1X2X3EditField            matlab.ui.control.NumericEditField
        X1X2X3CheckBox             matlab.ui.control.CheckBox
        X2X3X3EditField            matlab.ui.control.NumericEditField
        X2X32EditFieldLabel        matlab.ui.control.Label
        X2X2X3EditField            matlab.ui.control.NumericEditField
        X22X3EditFieldLabel        matlab.ui.control.Label
        X1X3X3EditField            matlab.ui.control.NumericEditField
        X1X32EditFieldLabel        matlab.ui.control.Label
        X1X2X2EditField            matlab.ui.control.NumericEditField
        X1X22Label                 matlab.ui.control.Label
        X1X1X3EditField            matlab.ui.control.NumericEditField
        X12X3Label                 matlab.ui.control.Label
        X1X1X2EditField            matlab.ui.control.NumericEditField
        X12X2Label                 matlab.ui.control.Label
        X2X3X3CheckBox             matlab.ui.control.CheckBox
        X2X2X3CheckBox             matlab.ui.control.CheckBox
        X1X3X3CheckBox             matlab.ui.control.CheckBox
        X1X1X2CheckBox             matlab.ui.control.CheckBox
        X1X2X2CheckBox             matlab.ui.control.CheckBox
        X1X1X3CheckBox             matlab.ui.control.CheckBox
        X3X3X3EditField            matlab.ui.control.NumericEditField
        X33EditFieldLabel          matlab.ui.control.Label
        X2X2X2EditField            matlab.ui.control.NumericEditField
        X23EditFieldLabel          matlab.ui.control.Label
        X1X1X1EditField            matlab.ui.control.NumericEditField
        X13EditFieldLabel          matlab.ui.control.Label
        X3X3X3CheckBox             matlab.ui.control.CheckBox
        X2X2X2CheckBox             matlab.ui.control.CheckBox
        X1X1X1CheckBox             matlab.ui.control.CheckBox
        X2X3CheckBox               matlab.ui.control.CheckBox
        X1X3CheckBox               matlab.ui.control.CheckBox
        X1X2CheckBox               matlab.ui.control.CheckBox
        X1X1CheckBox               matlab.ui.control.CheckBox
        X3X3CheckBox               matlab.ui.control.CheckBox
        X2X2CheckBox               matlab.ui.control.CheckBox
        X3CheckBox                 matlab.ui.control.CheckBox
        X2CheckBox                 matlab.ui.control.CheckBox
        X1CheckBox                 matlab.ui.control.CheckBox
        boCheckBox                 matlab.ui.control.CheckBox
        X2X3EditField              matlab.ui.control.NumericEditField
        X2X3EditFieldLabel         matlab.ui.control.Label
        X1X3EditField              matlab.ui.control.NumericEditField
        X1X3EditFieldLabel         matlab.ui.control.Label
        X1X2EditField              matlab.ui.control.NumericEditField
        X1X2EditFieldLabel         matlab.ui.control.Label
        X3X3EditField              matlab.ui.control.NumericEditField
        X32EditFieldLabel          matlab.ui.control.Label
        X2X2EditField              matlab.ui.control.NumericEditField
        X22EditFieldLabel          matlab.ui.control.Label
        X1X1EditField              matlab.ui.control.NumericEditField
        X12EditFieldLabel          matlab.ui.control.Label
        X3EditField                matlab.ui.control.NumericEditField
        X3EditFieldLabel           matlab.ui.control.Label
        X2EditField                matlab.ui.control.NumericEditField
        X2EditFieldLabel           matlab.ui.control.Label
        X1EditField                matlab.ui.control.NumericEditField
        X1EditFieldLabel           matlab.ui.control.Label
        boEditField                matlab.ui.control.NumericEditField
        boEditFieldLabel           matlab.ui.control.Label
        PontotimoLabel             matlab.ui.control.Label
        optimizeTable              matlab.ui.control.Table
        AnovaLabel                 matlab.ui.control.Label
        PlotarButton               matlab.ui.control.Button
        NmerodePontosSpinner       matlab.ui.control.Spinner
        MaiorInclinaoButton        matlab.ui.control.Button
        MinimizarButton            matlab.ui.control.Button
        MaximizarButton            matlab.ui.control.Button
        FTestTable                 matlab.ui.control.Table
        PasteTextArea              matlab.ui.control.TextArea
        CoeficientesdoModeloLabel  matlab.ui.control.Label
        InitialConditionsTable     matlab.ui.control.Table
        BoundsTable                matlab.ui.control.Table
        FactorSelectionGroup       matlab.ui.container.ButtonGroup
        ThreeFactorsRadioButton    matlab.ui.control.RadioButton
        TwoFactorsRadioButton      matlab.ui.control.RadioButton
        TabeladeinputsLabel        matlab.ui.control.Label
        InputTable                 matlab.ui.control.Table
        GerarPlanejamentoButton    matlab.ui.control.Button
        CriarModeloButton          matlab.ui.control.Button
        graficoResiduos            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Grafico 
        B
        OptP
        OptY
        Plan
    end
    
    methods (Access = private)

        function importarPlanilha(app)
            % Abrir uma caixa de diálogo para selecionar o arquivo
            [file, path] = uigetfile({'*.xlsx;*.xls;*.csv', 'Planilhas (*.xlsx, *.xls, *.csv)'}, ...
                                     'Selecionar arquivo de planilha');
            if isequal(file, 0)
                % O usuário cancelou a seleção
                return;
            end
            
            % Caminho completo do arquivo
            fullPath = fullfile(path, file);
        
            % Tentar carregar o arquivo
            try
                if endsWith(file, '.csv')
                    data = readtable(fullPath, 'PreserveVariableNames', true);
                else
                    data = readtable(fullPath, 'PreserveVariableNames', true);
                end
                
                % Verificar o número de colunas no arquivo
                numCols = size(data, 2);
                if numCols < 3 || numCols > 4
                    uialert(app.UIFigure, 'O arquivo deve conter 3 ou 4 colunas (X1, X2, [X3], Y).', ...
                            'Erro de Importação');
                    return;
                end
                
                % Atualizar os nomes das colunas da tabela de inputs
                if numCols == 3
                    app.InputTable.ColumnName = {'X1', 'X2', 'Y'};
                elseif numCols == 4
                    app.InputTable.ColumnName = {'X1', 'X2', 'X3', 'Y'};
                end
                
                % Atualizar os dados da tabela de inputs
                app.InputTable.Data = table2array(data);
                
                % Exibir mensagem de sucesso
                uialert(app.UIFigure, 'Dados importados com sucesso!', 'Importação Bem-Sucedida');
            catch ME
                % Exibir mensagem de erro
                uialert(app.UIFigure, ['Erro ao importar o arquivo: ', ME.message], ...
                        'Erro de Importação');
            end
        end

        function importarBounds(app)
            % Abrir uma caixa de diálogo para selecionar o arquivo
            [file, path] = uigetfile({'*.xlsx;*.xls;*.csv', 'Planilhas (*.xlsx, *.xls, *.csv)'}, ...
                                     'Selecionar arquivo de vértices');
            if isequal(file, 0)
                % O usuário cancelou a seleção
                return;
            end
            
            % Caminho completo do arquivo
            fullPath = fullfile(path, file);
        
            % Tentar carregar o arquivo
            try
                if endsWith(file, '.csv')
                    data = readtable(fullPath, 'PreserveVariableNames', true);
                else
                    data = readtable(fullPath, 'PreserveVariableNames', true);
                end
                
                % Verificar o número de colunas no arquivo
                numCols = size(data, 2);
                if numCols ~= 2 & numCols ~= 3
                    uialert(app.UIFigure, 'O arquivo deve conter exatamente 2 ou 3 colunas.', ...
                            'Erro de Importação');
                    return;
                end
                
                % Atualizar os nomes das colunas da tabela de limites (BoundsTable)
                if numCols == 2
                    app.BoundsTable.ColumnName = {'X1', 'X2'};
                else if numCols == 3
                    app.BoundsTable.ColumnName = {'X1', 'X2', 'X3'}; 
                end
                end
                % Atualizar os dados da tabela de limites
                app.BoundsTable.Data = table2array(data);
                
                % Exibir mensagem de sucesso
                uialert(app.UIFigure, 'Vértices importados com sucesso!', 'Importação Bem-Sucedida');
            catch ME
                % Exibir mensagem de erro
                uialert(app.UIFigure, ['Erro ao importar o arquivo: ', ME.message], ...
                        'Erro de Importação');
            end
        end


        function pontosFCC = planejarFCC(app, v)
            % v1: vértice inicial do paralelepípedo (menor valor nas 3 coordenadas)
            % v2: vértice oposto do paralelepípedo (maior valor nas 3 coordenadas)
            % pontosFCC: matriz contendo os pontos do planejamento cúbico de face centrada
        try
            v1 = [min(v(:,1)), min(v(:,2)), min(v(:,3))];
            v2 = [max(v(:,1)), max(v(:,2)), max(v(:,3))];
        
            % 1. Definir os 8 vértices do paralelepípedo
            vertices = [
                v1(1), v1(2), v1(3);
                v2(1), v1(2), v1(3);
                v1(1), v2(2), v1(3);
                v2(1), v2(2), v1(3);
                v1(1), v1(2), v2(3);
                v2(1), v1(2), v2(3);
                v1(1), v2(2), v2(3);
                v2(1), v2(2), v2(3)
            ];
        
            % 2. Calcular os centros das faces (6 faces)
            centrosFaces = [
                mean(vertices([1, 2, 5, 6], :), 1); % Face inferior (X1 varia)
                mean(vertices([3, 4, 7, 8], :), 1); % Face superior (X1 varia)
                mean(vertices([1, 3, 5, 7], :), 1); % Face esquerda (X2 varia)
                mean(vertices([2, 4, 6, 8], :), 1); % Face direita (X2 varia)
                mean(vertices([1, 2, 3, 4], :), 1); % Face frontal (X3 varia)
                mean(vertices([5, 6, 7, 8], :), 1)  % Face traseira (X3 varia)
            ];
        
            center = mean(v,1);
        
            % 3. Combinar vértices e centros das faces
            pontosFCC = [v; centrosFaces; center];
            
            for i=1:size(pontosFCC, 1)
                while ~inpolyhedron(convhull(v), v, pontosFCC(i,:))
                    pontosFCC(i,:) = (8*pontosFCC(i,:) + 2*mean(v,1))./10;
                end
            end
                    
            % 5. Plotar para visualização
            
            scatter3(app.Plan,v(:, 1), v(:, 2), v(:, 3), 100, 'r', 'filled');
            hold (app.Plan, 'on');
            scatter3(app.Plan,centrosFaces(:, 1), centrosFaces(:, 2), centrosFaces(:, 3), 100, 'b', 'filled');
            scatter3(app.Plan, center(1), center(2), center(3), 100, 'r', 'filled');
            title(app.Plan,'Planejamento Cúbico de Face Centrada');
            xlabel(app.Plan,'X1');
            ylabel(app.Plan,'X2');
            zlabel(app.Plan,'X3');
            grid (app.Plan,'on');
            hold(app.Plan, 'off');
        catch ME
            uialert(app.UIFigure, ['Erro ao gerar planejamento: ', ME.message], ...
                        'Erro de Planejamento');
        end
            
        end

        function pontosSTR = planejarSTR(app, vertices)
        try
            % Função para gerar pontos de planejamento estrela em uma região 2D definida
            % pelos vértices 'vertices'
        
            % 1. Encontrar o centro do polígono
            center = mean(vertices, 1);
        
            % 2. Definir direções estrela
            A = (max(vertices(:, 2)) - min(vertices(:, 2))) / 2; % Altura
            B = (max(vertices(:, 1)) - min(vertices(:, 1))) / 2; % Largura
        
            % Pontos estrela ao longo das direções principais e diagonais
            pontosDirecao = [
                center + [B, 0];    % Direção direita
                center - [B, 0];    % Direção esquerda
                center + [0, A];    % Direção superior
                center - [0, A];    % Direção inferior
                center + [B/sqrt(2), A/sqrt(2)]; % Diagonal superior direita
                center + [-B/sqrt(2), A/sqrt(2)]; % Diagonal superior esquerda
                center + [B/sqrt(2), -A/sqrt(2)]; % Diagonal inferior direita
                center + [-B/sqrt(2), -A/sqrt(2)] % Diagonal inferior esquerda
            ];
        
            % 3. Ajustar pontos para estarem dentro do polígono
            k = convhull(vertices(:,1), vertices(:,2)); % Polígono convexo
            vertices = vertices(k, :); % Reordenar vértices
        
            % Ajustar pontos para dentro do polígono
            for i = 1:size(pontosDirecao, 1)
                while ~inpolygon(pontosDirecao(i, 1), pontosDirecao(i, 2), vertices(:, 1), vertices(:, 2))
                    % Ajustar em direção ao centro
                    pontosDirecao(i, :) = (8 * pontosDirecao(i, :) + 2 * center) / 10;
                end
            end
        
            % 4. Combinar com o centro
            pontosSTR = [center; pontosDirecao];
        
            % 5. Plotar os pontos do planejamento estrela
            plot(app.Plan, vertices(:, 1), vertices(:, 2), 'k-', 'LineWidth', 1.5); % Polígono
            hold(app.Plan, 'on');
            scatter(app.Plan, pontosSTR(:, 1), pontosSTR(:, 2), 100, 'b', 'filled'); % Pontos estrela
            scatter(app.Plan, center(1), center(2), 120, 'r', 'filled'); % Centro
            title(app.Plan, 'Planejamento Estrela');
            xlabel(app.Plan, 'X1');
            ylabel(app.Plan, 'X2');
            grid(app.Plan, 'on');
            hold(app.Plan, 'off');
        catch ME
            uialert(app.UIFigure, ['Erro ao gerar planejamento: ', ME.message], ...
                        'Erro de Planejamento');
        end
        end


        function val = penalized_function_min(app, x, f, vertices)
            % Verifica se o ponto está dentro do polígono
            if inpolygon(x(1), x(2), vertices(:, 1), vertices(:, 2))
                val = f(x); % Avalia a função
            else
                val = 1e9; % Penaliza fortemente pontos fora
            end
        end

        function val = penalized_function_max(app, x, f, vertices)
            % Verifica se o ponto está dentro do polígono
            if inpolygon(x(1), x(2), vertices(:, 1), vertices(:, 2))
                val = f(x); % Avalia a função
            else
                val = -1e9; % Penaliza fortemente pontos fora
            end
        end

        function val = penalized_function_min_3f(app, x, f, vertices)
            % Verifica se o ponto está dentro do poliedro
            if inpolyhedron(convhull(vertices), vertices, x)
                val = f(x); % Avalia a função
            else
                val = 1e9; % Penaliza fortemente pontos fora
            end
        end
        
        function val = penalized_function_max_3f(app, x, f, vertices)
            % Verifica se o ponto está dentro do poliedro
            if inpolyhedron(convhull(vertices), vertices, x)
                val = f(x); % Avalia a função
            else
                val = -1e9; % Penaliza fortemente pontos fora
            end
        end

        function [outputArg1,outputArg2,outputArg3,outputArg4,outputArg5, outputArg6] = gerarModelo3f(app, X1, X2, X3, Y)
            %   Gera modelo baseado em matrizes de inputs X1, X2 e X3 e outputs 
            %   Y
            
        try
            % Garantir que X1, X2, X3, Y são coluna
            X1 = X1(:);
            X2 = X2(:);
            X3 = X3(:);
            Y = Y(:);
        
            % Verifique se X1, X2, e X3 possuem o mesmo comprimento
            if length(X1) == length(X2) && length(X2) == length(X3)
                % Construa a matriz X se as dimensões estiverem consistentes
                X = [ones(length(X1), 1), X1, X2, X3, X1.^2, X2.^2, X3.^2, ...
                     X1.*X2, X1.*X3, X2.*X3, X1.^3, X2.^3, X3.^3, ...
                     (X1.^2).*X2, (X1.^2).*X3, X1.*(X2.^2), X1.*(X3.^2), (X2.^2).*X3, ...
                     X2.*(X3.^2), X1.*X2.*X3];
            else
                error('X1, X2, e X3 devem ter o mesmo número de elementos.');
            end
            % Remover coeficientes ignorados e preparar b
            b = ones(20,1);
            
            ToRemove = [];
            if ~app.boCheckBox.Value, b(1) = 0; ToRemove(end+1) = 1; end
            if ~app.X1CheckBox.Value, b(2) = 0; ToRemove(end+1) = 2; end
            if ~app.X2CheckBox.Value, b(3) = 0; ToRemove(end+1) = 3; end
            if ~app.X3CheckBox.Value, b(4) = 0; ToRemove(end+1) = 4; end
            if ~app.X1X1CheckBox.Value, b(5) = 0; ToRemove(end+1) = 5; end
            if ~app.X2X2CheckBox.Value, b(6) = 0; ToRemove(end+1) = 6; end
            if ~app.X3X3CheckBox.Value, b(7) = 0; ToRemove(end+1) = 7; end
            if ~app.X1X2CheckBox.Value, b(8) = 0; ToRemove(end+1) = 8; end
            if ~app.X1X3CheckBox.Value, b(9) = 0; ToRemove(end+1) = 9; end
            if ~app.X2X3CheckBox.Value, b(10) = 0; ToRemove(end+1) = 10; end
            if ~app.X1X1X1CheckBox.Value, b(11) = 0; ToRemove(end+1) = 11; end
            if ~app.X2X2X2CheckBox.Value, b(12) = 0; ToRemove(end+1) = 12; end
            if ~app.X3X3X3CheckBox.Value, b(13) = 0; ToRemove(end+1) = 13; end
            if ~app.X1X1X2CheckBox.Value, b(14) = 0; ToRemove(end+1) = 14; end
            if ~app.X1X1X3CheckBox.Value, b(15) = 0; ToRemove(end+1) = 15; end
            if ~app.X1X2X2CheckBox.Value, b(16) = 0; ToRemove(end+1) = 16; end
            if ~app.X1X3X3CheckBox.Value, b(17) = 0; ToRemove(end+1) = 17; end
            if ~app.X2X2X3CheckBox.Value, b(18) = 0; ToRemove(end+1) = 18; end
            if ~app.X2X3X3CheckBox.Value, b(19) = 0; ToRemove(end+1) = 19; end
            if ~app.X1X2X3CheckBox.Value, b(20) = 0; ToRemove(end+1) = 20; end


            X(:, ToRemove) = [];
        
            % Passo 1: Calcular X^T * X
            XtX = (X') * X;
        
            % Passo 2: Calcular X^T * Y
            XtY = (X') * Y;
        
            % Passo 3: Resolver o sistema linear para encontrar os coeficientes b
            b_raw = XtX \ XtY;
          

            % Preencher com b(i) = 0 para os coeficientes que foram
            % ignorados
            i = 1;
            j = 1;
            while i <= length(b) 
                if b(i) == 0
                    i = i + 1;
                else
                    b(i) = b_raw(j);
                    i = i + 1;
                    j = j + 1;
                end
            end
        
            %% Análise da significância dos coeficientes:
        
            % Número de observações e variáveis
            n = length(Y);
            p = size(X, 2);
        
            % Estimar os valores preditos
            Y_pred = X * b_raw;
        
            % Calcular os resíduos
            residuals = Y - Y_pred;
        
            % Estimar o erro padrão dos resíduos
            sigma2 = sum(residuals.^2) / (n - p);
        
            % Calcular a matriz de variâncias e covariâncias dos coeficientes
            cov_matrix = sigma2 * inv(XtX);
        
            % Erro padrão dos coeficientes
            stderr = sqrt(diag(cov_matrix));
        
            % Calcular os valores t
            t_values = b_raw ./ stderr;
        
            % Calcular os valores p para os testes t (H0: coeficiente = 0)
            p_values = 2 * (1 - tcdf(abs(t_values), n - p));
            
            %% Análise Anova
        
            % Soma dos quadrados total (SST)
            SST = sum((Y - mean(Y)).^2);
        
            % Soma dos quadrados explicada pelo modelo (SSR)
            SSR = sum((Y_pred - mean(Y)).^2);
        
            % Soma dos quadrados dos resíduos (SSE)
            SSE = sum((Y - Y_pred).^2);
        
            % Valor de R2 e R2 ajustado
            R2 = 1 - (SSE / SST);
            R2_adj = 1 - (SSE / (n - p)) / (SST / (n - 1));
        
            % Graus de liberdade
            df_total = n - 1;
            df_model = p - 1;
            df_residual = n - p;
        
            % Mean Square (MS)
            MS_model = SSR / df_model;
            MS_residual = SSE / df_residual;
        
            % Estatística F
            F_value = MS_model / MS_residual;
        
            % Valor p para o teste F (H0: Todos os coeficientes = 0)
            p_F = 1 - fcdf(F_value, df_model, df_residual);

            % Calcular valor F para cada parâmetro
            F_param = zeros(p, 1);
            for i = 2:p  
                X_reduzido = X;  % Copiar matriz X
                X_reduzido(:, i) = [];  % Remover a coluna do parâmetro i
        
                % Modelo reduzido
                b_reduzido = (X_reduzido' * X_reduzido) \ (X_reduzido' * Y);
                Y_pred_reduzido = X_reduzido * b_reduzido;
                SSE_reduzido = sum((Y - Y_pred_reduzido).^2);
        
                % Calcular valor F para o parâmetro i
                F_param(i) = ((SSE_reduzido - SSE) / 1) / MS_residual;
            end


            b_raw = [b_raw, F_param, stderr];
          
         
            outputArg1 = b;
            outputArg2 = [df_model, df_residual];
            outputArg3 = F_value;
            outputArg4 = [residuals, Y_pred];
            outputArg5 = b_raw;
            outputArg6 = [SSE, SST, R2_adj];
        catch ME
            uialert(app.UIFigure, ['Erro ao gerar modelo: ', ME.message], ...
                        'Erro de Modelo');
        end
            
        end

        function [outputArg1,outputArg2,outputArg3,outputArg4,outputArg5, outputArg6] = gerarModelo2f(app, X1, X2, Y)
        %   Gera modelo baseado em matrizes de inputs e outputs
        %   Generalização do script do Corotinho, sem elementos de interface
        %   gráfica
        try
            % Construindo a matriz X (incluindo o termo constante)
            X = [ones(length(X1), 1), X1, X2, X1.^2, X2.^2, X1.*X2, X1.^3, X2.^3, X1.*X1.*X2, X1.*X2.*X2];

            % Remover coeficientes ignorados e preparar b
            b = ones(10,1);
         
            ToRemove = [];
            if ~app.boCheckBox.Value, b(1) = 0; ToRemove(end+1) = 1; end       % Intercepto (b0)
            if ~app.X1CheckBox.Value, b(2) = 0; ToRemove(end+1) = 2; end       % Coeficiente de X1
            if ~app.X2CheckBox.Value, b(3) = 0; ToRemove(end+1) = 3; end       % Coeficiente de X2
            if ~app.X1X1CheckBox.Value, b(4) = 0; ToRemove(end+1) = 4; end     % Coeficiente de X1^2
            if ~app.X2X2CheckBox.Value, b(5) = 0; ToRemove(end+1) = 5; end     % Coeficiente de X2^2
            if ~app.X1X2CheckBox.Value, b(6) = 0; ToRemove(end+1) = 6; end     % Coeficiente de X1*X2
            if ~app.X1X1X1CheckBox.Value, b(7) = 0; ToRemove(end+1) = 7; end   % Coeficiente de X1^3
            if ~app.X2X2X2CheckBox.Value, b(8) = 0; ToRemove(end+1) = 8; end   % Coeficiente de X2^3
            if ~app.X1X1X2CheckBox.Value, b(9) = 0; ToRemove(end+1) = 9; end   % Coeficiente de X1^2*X2
            if ~app.X1X2X2CheckBox.Value, b(10) = 0; ToRemove(end+1) = 10; end % Coeficiente de X1*X2^2

            X(:, ToRemove) = [];
            
        
            % Passo 1: Calcular X^T * X
            XtX = (X') * X;
        
            % Passo 2: Calcular X^T * Y
            XtY = (X') * Y;
        
            % Passo 3: Resolver o sistema linear para encontrar os coeficientes b
            b_raw = XtX \ XtY;
          

            % Preencher com b(i) = 0 para os coeficientes que foram
            % ignorados
            i = 1;
            j = 1;
            while i <= length(b) 
                if b(i) == 0
                    i = i + 1;
                else
                    b(i) = b_raw(j);
                    i = i + 1;
                    j = j + 1;
                end
            end


            %% Análise da significância dos coeficientes:
        
            % Número de observações e variáveis
            n = length(Y);
            p = size(X, 2);
        
            % Estimar os valores preditos
            Y_pred = X * b_raw;
        
            % Calcular os resíduos
            residuals = Y - Y_pred;
        
            % Estimar o erro padrão dos resíduos
            sigma2 = sum(residuals.^2) / (n - p);
        
            % Calcular a matriz de variâncias e covariâncias dos coeficientes
            cov_matrix = sigma2 * inv(XtX);
        
            % Erro padrão dos coeficientes
            stderr = sqrt(diag(cov_matrix));
        
            % Calcular os valores t
            t_values = b_raw ./ stderr;
        
            % Calcular os valores p para os testes t (H0: coeficiente = 0)
            p_values = 2 * (1 - tcdf(abs(t_values), n - p));
            
            %% Análise Anova
        
            % Soma dos quadrados total (SST)
            SST = sum((Y - mean(Y)).^2);
        
            % Soma dos quadrados explicada pelo modelo (SSR)
            SSR = sum((Y_pred - mean(Y)).^2);
        
            % Soma dos quadrados dos resíduos (SSE)
            SSE = sum((Y - Y_pred).^2);
        
            % Valor de R2 e R2 ajustado
            R2 = 1 - (SSE / SST);
            R2_adj = 1 - (SSE / (n - p)) / (SST / (n - 1));
        
            % Graus de liberdade
            df_total = n - 1;
            df_model = p - 1;
            df_residual = n - p;
        
            % Mean Square (MS)
            MS_model = SSR / df_model;
            MS_residual = SSE / df_residual;
        
            % Estatística F
            F_value = MS_model / MS_residual;
        
            % Valor p para o teste F (H0: Todos os coeficientes = 0)
            p_F = 1 - fcdf(F_value, df_model, df_residual);

            % Calcular valor F para cada parâmetro
            F_param = zeros(p, 1);
            for i = 2:p  
                X_reduzido = X;  % Copiar matriz X
                X_reduzido(:, i) = [];  % Remover a coluna do parâmetro i
        
                % Modelo reduzido
                b_reduzido = (X_reduzido' * X_reduzido) \ (X_reduzido' * Y);
                Y_pred_reduzido = X_reduzido * b_reduzido;
                SSE_reduzido = sum((Y - Y_pred_reduzido).^2);
        
                % Calcular valor F para o parâmetro i
                F_param(i) = ((SSE_reduzido - SSE) / 1) / MS_residual;
            end

            b_raw = [b_raw, F_param, stderr];

            % Habilitar maior inclinação se o modelo for linear
            if app.X1X1CheckBox.Value == 0 && app.X2X2CheckBox.Value == 0 && ...
               app.X1X2CheckBox.Value == 0 && ...
               app.X1X1X1CheckBox.Value == 0 && app.X2X2X2CheckBox.Value == 0 && ...
               app.X1X1X2CheckBox.Value == 0 && app.X1X2X2CheckBox.Value == 0

               app.MaiorInclinaoButton.Visible = 'on';
               app.NmerodePontosSpinner.Visible = 'on';
               app.InitialConditionsTable.Visible = 'on';
               app.InitialConditionsTable.ColumnName = {'X1', 'X2'};
               app.InitialConditionsTable.Data = [0 0];
            else
               app.MaiorInclinaoButton.Visible = 'off';
               app.NmerodePontosSpinner.Visible = 'off';
               app.InitialConditionsTable.Visible = 'off';
          
            end
          
         
            outputArg1 = b;
            outputArg2 = [df_model, df_residual];
            outputArg3 = F_value;
            outputArg4 = [residuals, Y_pred];
            outputArg5 = b_raw;
            outputArg6 = [SSE, SST, R2_adj];
        catch ME
            uialert(app.UIFigure, ['Erro ao gerar modelo: ', ME.message], ...
                        'Erro de Modelo');
        end
        end
               

        function maximize2f(app, b, vertices)
        try

            % Coeficientes da função (usando os valores obtidos em b)
            b0 = b(1);
            b1 = b(2);
            b2 = b(3);
            b3 = b(4);
            b4 = b(5);
            b5 = b(6);
            b6 = b(7);
            b7 = b(8);
            b8 = b(9);
            b9 = b(10);
           

            % Função anônima para o modelo
            f = @(c) (b0 + b1*c(1) + b2*c(2) + b3*(c(1).^2) + b4*(c(2).^2) + b5*c(1).*c(2) +...
                b6*(c(1).^3) + b7*(c(2).^3) + b8*(c(1).^2.*c(2)) + b9*(c(1).*c(2).^2));

            k = convhull(vertices(:,1), vertices(:,2));
            vertices = vertices(k,:);

            max_val = -inf;

            for i = 1:size(vertices,1)
                val = f(vertices(i,:));
                if val > max_val
                    max_val = val;
                    max_point = vertices(i,:);
                end
            end

            penalized_f_max = @(x) penalized_function_max(app, x, f, vertices);

            % Otimização no interior do polígono
            lb = [min(vertices(:, 1)), min(vertices(:, 2))]; % Limites inferiores
            ub = [max(vertices(:, 1)), max(vertices(:, 2))]; % Limites superiores
            
            options = optimoptions('fmincon', 'Display', 'none', 'Algorithm', 'sqp');
            
            % Encontrar o máximo
            fval_max = -inf;
            IC = [mean(vertices); mean(vertices([1,2],:)); mean(vertices([2,3],:)); mean(vertices([3,4],:)); mean(vertices([1,4],:))];
            for i = 1:size(vertices, 1)
                [x_max_aux, fval_max_aux] = fmincon(@(x) -penalized_f_max(x), IC(i,:), [], [], [], [], lb, ub, [], options);
                fval_max_aux = -fval_max_aux;
                if fval_max_aux > fval_max
                    fval_max = fval_max_aux;
                    x_max = x_max_aux;
                end
            end
            % Comparar com valores nos vértices
            if fval_max > max_val
                max_val = fval_max;
                max_point = x_max;
            end

            % Mostrar ponto ótimo na tabela
            app.optimizeTable.ColumnName = {'X1', 'X2', 'Y'};
            app.optimizeTable.Data = [max_point(1), max_point(2), max_val];

            app.OptP = max_point;
            app.OptY = max_val;
        catch ME
            uialert(app.UIFigure, ['Erro ao determinar máximo: ', ME.message], ...
                        'Erro de Otimização');
        end
        end

        function minimize2f(app, b, vertices)
        try    
            % Coeficientes da função (usando os valores obtidos em b)
            b0 = b(1);
            b1 = b(2);
            b2 = b(3);
            b3 = b(4);
            b4 = b(5);
            b5 = b(6);
            b6 = b(7);
            b7 = b(8);
            b8 = b(9);
            b9 = b(10);
           

            % Função anônima para o modelo
            f = @(c) (b0 + b1*c(1) + b2*c(2) + b3*(c(1).^2) + b4*(c(2).^2) + b5*c(1).*c(2) +...
                b6*(c(1).^3) + b7*(c(2).^3) + b8*(c(1).^2.*c(2)) + b9*(c(1).*c(2).^2));

            k = convhull(vertices(:,1), vertices(:,2));
            vertices = vertices(k,:);

            min_val = inf;

            for i = 1:size(vertices,1)
                val = f(vertices(i,:));
                if val < min_val
                    min_val = val;
                    min_point = vertices(i,:);
                end
            end

            penalized_f_min = @(x) penalized_function_min(app, x, f, vertices);

            % Otimização no interior do polígono
            lb = [min(vertices(:, 1)), min(vertices(:, 2))]; % Limites inferiores
            ub = [max(vertices(:, 1)), max(vertices(:, 2))]; % Limites superiores
            
            options = optimoptions('fmincon', 'Display', 'none', 'Algorithm', 'sqp');
            
            % Encontrar o máximo
            fval_min = inf;
            IC = [mean(vertices); mean(vertices([1,2],:)); mean(vertices([2,3],:)); mean(vertices([3,4],:)); mean(vertices([1,4],:))];
            for i = 1:size(vertices, 1)
                [x_min_aux, fval_min_aux] = fmincon(@(x) penalized_f_min(x), IC(i,:), [], [], [], [], lb, ub, [], options);
                if fval_min_aux < fval_min
                    fval_min = fval_min_aux;
                    x_min = x_min_aux;
                end
            end
            % Comparar com valores nos vértices
            if fval_min < min_val
                min_val = fval_min;
                min_point = x_min;
            end

            % Mostrar ponto ótimo na tabela
            app.optimizeTable.ColumnName = {'X1', 'X2', 'Y'};
            app.optimizeTable.Data = [min_point(1), min_point(2), min_val];

            app.OptP = min_point;
            app.OptY = min_val;
        catch ME
            uialert(app.UIFigure, ['Erro ao determinar mínimo: ', ME.message], ...
                        'Erro de Otimização');
        end
        end

        function minimize3f(app, b, vertices)
        try
            % Coeficientes da função
            b0 = b(1);
            b1 = b(2); b2 = b(3); b3 = b(4); % Lineares
            b4 = b(5); b5 = b(6); b6 = b(7); % Quadráticos
            b7 = b(8); b8 = b(9); b9 = b(10); % Interações
            b10 = b(11); b11 = b(12); b12 = b(13); % Cúbicos
            b13 = b(14); b14 = b(15); b15 = b(16);
            b16 = b(17); b17 = b(18); b18 = b(19);
            b19 = b(20);
        
            % Função anônima para o modelo
            f = @(c) b0 + ...
                b1*c(1) + b2*c(2) + b3*c(3) + ...                % Termos lineares
                b4*(c(1).^2) + b5*(c(2).^2) + b6*(c(3).^2) + ...       % Termos quadráticos
                b7*(c(1).*c(2)) + b8*(c(1).*c(3)) + b9*(c(2).*c(3)) + ... % Termos de interação de segunda ordem
                b10*(c(1).^3) + b11*(c(2).^3) + b12*(c(3).^3) + ...    % Termos cúbicos
                b13*(c(1).^2).*c(2) + b14*(c(1).^2).*c(3) + ...      % Interações cúbicas 
                b15*(c(2).^2).*c(1) + b16*(c(3).^2).*c(1) + ...      % Interações cúbicas 
                b17*(c(2).^2).*c(3) + b18*(c(3).^2).*c(2) + ...      % Interações cúbicas 
                b19*c(1).*c(2).*c(3);
        
            % Determina o envoltório convexo dos vértices
            k = convhull(vertices(:,1), vertices(:,2), vertices(:,3));
            vertices = vertices(unique(k(:)), :); % Filtra vértices únicos
        
            min_val = inf;
        
            % Avalia nos vértices
            for i = 1:size(vertices, 1)
                val = f(vertices(i, :));
                if val < min_val
                    min_val = val;
                    min_point = vertices(i, :);
                end
            end
        
            penalized_f_min = @(x) penalized_function_max_3f(app, x, f, vertices);
        
            % Otimização no interior do poliedro
            lb = min(vertices); % Limites inferiores
            ub = max(vertices); % Limites superiores
        
            options = optimoptions('fmincon', 'Display', 'none', 'Algorithm', 'sqp');
        
            IC = mean(vertices, 1);
            [x_min, fval_min] = fmincon(@(x) penalized_f_min(x), IC, [], [], [], [], lb, ub, [], options);
         
            for i = 1:size(vertices, 1)
                [x_min_aux, fval_min_aux] = fmincon(@(x) penalized_f_min(x), vertices(i, :), [], [], [], [], lb, ub, [], options);
                fval_min_aux = -fval_min_aux;
                if fval_min_aux < fval_min
                    fval_min = fval_min_aux;
                    x_min = x_min_aux;
                end
            end
        
            % Comparar com valores nos vértices
            if fval_min < min_val
                min_val = fval_min;
                min_point = x_min;
            end
        
            % Mostrar ponto ótimo na tabela
            app.optimizeTable.ColumnName = {'X1', 'X2', 'X3', 'Y'};
            app.optimizeTable.Data = [min_point(1), min_point(2), min_point(3), min_val];
        
            app.OptP = min_point;
            app.OptY = min_val;
        catch ME
            uialert(app.UIFigure, ['Erro ao determinar mínimo: ', ME.message], ...
                        'Erro de Otimização');
        end
        end

        function maximize3f(app, b, vertices)
        try
            % Coeficientes da função
            b0 = b(1);
            b1 = b(2); b2 = b(3); b3 = b(4); % Lineares
            b4 = b(5); b5 = b(6); b6 = b(7); % Quadráticos
            b7 = b(8); b8 = b(9); b9 = b(10); % Interações
            b10 = b(11); b11 = b(12); b12 = b(13); % Cúbicos
            b13 = b(14); b14 = b(15); b15 = b(16);
            b16 = b(17); b17 = b(18); b18 = b(19);
            b19 = b(20);
        
            % Função anônima para o modelo
            f = @(c) b0 + ...
                b1*c(1) + b2*c(2) + b3*c(3) + ...                % Termos lineares
                b4*(c(1).^2) + b5*(c(2).^2) + b6*(c(3).^2) + ...       % Termos quadráticos
                b7*(c(1).*c(2)) + b8*(c(1).*c(3)) + b9*(c(2).*c(3)) + ... % Termos de interação de segunda ordem
                b10*(c(1).^3) + b11*(c(2).^3) + b12*(c(3).^3) + ...    % Termos cúbicos
                b13*(c(1).^2).*c(2) + b14*(c(1).^2).*c(3) + ...      % Interações cúbicas 
                b15*(c(2).^2).*c(1) + b16*(c(3).^2).*c(1) + ...      % Interações cúbicas 
                b17*(c(2).^2).*c(3) + b18*(c(3).^2).*c(2) + ...      % Interações cúbicas 
                b19*c(1).*c(2).*c(3);
        
            % Determina o envoltório convexo dos vértices
            k = convhull(vertices(:,1), vertices(:,2), vertices(:,3));
            vertices = vertices(unique(k(:)), :); % Filtra vértices únicos
        
            max_val = -inf;
        
            % Avalia nos vértices
            for i = 1:size(vertices, 1)
                val = f(vertices(i, :));
                if val > max_val
                    max_val = val;
                    max_point = vertices(i, :);
                end
            end
        
            penalized_f_max = @(x) penalized_function_max_3f(app, x, f, vertices);
        
            % Otimização no interior do poliedro
            lb = min(vertices); % Limites inferiores
            ub = max(vertices); % Limites superiores
        
            options = optimoptions('fmincon', 'Display', 'none', 'Algorithm', 'sqp');
        
            fval_max = -inf;
            IC = mean(vertices, 1);
            [x_max, fval_max] = fmincon(@(x) -penalized_f_max(x), IC, [], [], [], [], lb, ub, [], options);
            fval_max = -fval_max;
            for i = 1:size(vertices, 1)
                [x_max_aux, fval_max_aux] = fmincon(@(x) -penalized_f_max(x), vertices(i, :), [], [], [], [], lb, ub, [], options);
                fval_max_aux = -fval_max_aux;
                if fval_max_aux > fval_max
                    fval_max = fval_max_aux;
                    x_max = x_max_aux;
                end
            end
        
            % Comparar com valores nos vértices
            if fval_max > max_val
                max_val = fval_max;
                max_point = x_max;
            end
        
            % Mostrar ponto ótimo na tabela
            app.optimizeTable.ColumnName = {'X1', 'X2', 'X3', 'Y'};
            app.optimizeTable.Data = [max_point(1), max_point(2), max_point(3), max_val];
        
            app.OptP = max_point;
            app.OptY = max_val;
        catch ME
            uialert(app.UIFigure, ['Erro ao determinar máximo: ', ME.message], ...
                        'Erro de Otimização');
        end
        end
        
        function plotModelSurface2f(app, b, lower_bounds, upper_bounds)
        try
            
            % Cria uma grade de pontos usando os limites inferiores e superiores
            [X1Grid, X2Grid] = meshgrid(linspace(lower_bounds(1), upper_bounds(1), 50), ...
                                        linspace(lower_bounds(2), upper_bounds(2), 50));
    
            % Avalia a função do modelo nos pontos da grade
            YGrid = b(1) + b(2)*X1Grid + b(3)*X2Grid + ...
                    b(4)*X1Grid.^2 + b(5)*X2Grid.^2 + b(6)*X1Grid.*X2Grid + ...
                    b(7)*X1Grid.^3 + b(8)*X2Grid.^3 + ...
                    b(9)*X1Grid.^2.*X2Grid + b(10)*X1Grid.*X2Grid.^2;


            % Toma limite superior e inferior 
            Ysup = max(max(YGrid));
            Yinf = min(min(YGrid));

            % Toma níveis para as curvas de nível
            resolution = 10;
            levels = linspace(Yinf, Ysup, resolution)';
    
            % Plotagem da superfície na nova janela
            surf(app.Grafico, X1Grid, X2Grid, YGrid);
            xlabel(app.Grafico, 'X1');
            ylabel(app.Grafico, 'X2');
            zlabel(app.Grafico, 'Y');
            title(app.Grafico, 'Superfície de resposta gerada pelo modelo');
            colormap(app.Grafico, 'hsv');
            hold(app.Grafico, 'on'); % Mantém o gráfico atual para adicionar mais elementos gráficos
            contour3(app.Grafico, X1Grid, X2Grid, YGrid, levels, 'k', 'LineWidth', 2);
            colorbar(app.Grafico);
            hold(app.Grafico, 'off')
        catch ME
            uialert(app.UIFigure, ['Erro ao exibir gráfico: ', ME.message], ...
                        'Erro de Exibição');
        end
        end

        function plotModelSurface3f(app, b, lower_bounds, upper_bounds)
        try
            % Plota nuvem de pontos codificados por cor em 3 dimensões em um cubo determinado por arrays upper_bounds e lower_bounds. 
            % b: vetor de coeficientes da função plotada
            

            % Número de pontos em cada dimensão 
            numPoints = 15;

            % Obter os limites inferiores e superiores da tabela
            vertices = app.BoundsTable.Data;
            lower_bounds = [min(vertices(:, 1)), min(vertices(:, 2)), min(vertices(:, 3))]; % Limites inferiores
            upper_bounds = [max(vertices(:, 1)), max(vertices(:, 2)), max(vertices(:, 3))]; % Limites superiores
        
            % Gera uma grade de pontos nos limites de X1, X2 e X3
            X1_values = linspace(lower_bounds(1), upper_bounds(1), numPoints);
            X2_values = linspace(lower_bounds(2), upper_bounds(2), numPoints);
            X3_values = linspace(lower_bounds(3), upper_bounds(3), numPoints);
        
            % Cria todas as combinações de X1, X2, X3
            [X1Grid, X2Grid, X3Grid] = ndgrid(X1_values, X2_values, X3_values);
        
            % Converte a grade em vetores coluna
            X1_points = X1Grid(:);
            X2_points = X2Grid(:);
            X3_points = X3Grid(:);
            
            b0 = b(1);
            b1 = b(2);
            b2 = b(3);
            b3 = b(4);
            b4 = b(5);
            b5 = b(6);
            b6 = b(7);
            b7 = b(8);
            b8 = b(9);
            b9 = b(10);
            b10 = b(11);
            b11 = b(12);
            b12 = b(13);
            b13 = b(14);
            b14 = b(15);
            b15 = b(16);
            b16 = b(17);
            b17 = b(18);
            b18 = b(19);
            b19 = b(20);
               
            model_func = @(c)...
            b0 + ...
            b1*c(1) + b2*c(2) + b3*c(3) + ...                % Termos lineares
            b4*(c(1).^2) + b5*(c(2).^2) + b6*(c(3).^2) + ...       % Termos quadráticos
            b7*(c(1).*c(2)) + b8*(c(1).*c(3)) + b9*(c(2).*c(3)) + ... % Termos de interação de segunda ordem
            b10*(c(1).^3) + b11*(c(2).^3) + b12*(c(3).^3) + ...    % Termos cúbicos
            b13*(c(1).^2).*c(2) + b14*(c(1).^2).*c(3) + ...      % Interações cúbicas 
            b15*(c(2).^2).*c(1) + b16*(c(3).^2).*c(1) + ...      % Interações cúbicas 
            b17*(c(2).^2).*c(3) + b18*(c(3).^2).*c(2) + ...      % Interações cúbicas 
            b19*c(1).*c(2).*c(3);
            % Calcula a resposta do modelo para cada ponto
            response_values = arrayfun(@(x1, x2, x3) model_func([x1, x2, x3]), X1_points, X2_points, X3_points);
        
            % Plota os pontos em 3D, com a cor representando a magnitude da resposta            
            scatter3(app.Grafico, X1_points, X2_points, X3_points, 20, response_values, 'filled');
            colorbar(app.Grafico); % Adiciona uma barra de cores para referência
            colormap(app.Grafico, 'jet'); % Define o mapa de cores
            xlabel(app.Grafico, 'X1');
            ylabel(app.Grafico, 'X2');
            zlabel(app.Grafico, 'X3');
            title(app.Grafico, 'Mapa de cores 3D da resposta');
            grid(app.Grafico, 'on');
        catch ME
            uialert(app.UIFigure, ['Erro ao exibir gráfico: ', ME.message], ...
                        'Erro de Exibição');
        end

        end

        function plotResiduals(app, Residuals)
        try
            if min(Residuals(:,2)) < 0
                a = 1.1*min(Residuals(:,2));
            else
                a = 0.9*min(Residuals(:,2));
            end
            if max(Residuals(:,2)) > 0
                b = 1.1*max(Residuals(:,2));
            else
                b = 0.9*max(Residuals(:,2));
            end
            A = [a, b];
            B = [0 0];

            % Plotar os pontos no componente graficoResiduos
            cla(app.graficoResiduos); % Limpar o gráfico existente
            hold(app.graficoResiduos, 'on');
            plot(app.graficoResiduos, Residuals(:,2), Residuals(:,1) , 'r.', 'MarkerSize', 8);
            plot(app.graficoResiduos, A, B, 'b');
            hold(app.graficoResiduos, 'off')

            xlabel(app.graficoResiduos, 'Y');
            ylabel(app.graficoResiduos, 'Resíduos');
            grid(app.graficoResiduos, 'on');
        catch ME
            uialert(app.UIFigure, ['Erro ao exibir resíduos: ', ME.message], ...
                        'Erro de Exibição');
        end
        end

        function updateModelCoefficientsTable(app, b, num_factors)
            % coef_details: vetor contendo coeficientes, inclusive 0 para
            % os ignorados
            % num_factors: Número de fatores (2 ou 3)
    
            if num_factors == 2
               
                app.boEditField.Value = b(1);       % Intercepto (b0)
                app.X1EditField.Value = b(2);       % Coeficiente de X1
                app.X2EditField.Value = b(3);       % Coeficiente de X2
                app.X1X1EditField.Value = b(4);     % Coeficiente de X1^2
                app.X2X2EditField.Value = b(5);     % Coeficiente de X2^2
                app.X1X2EditField.Value = b(6);     % Coeficiente de X1*X2
                app.X1X1X1EditField.Value = b(7);   % Coeficiente de X1^3
                app.X2X2X2EditField.Value = b(8);   % Coeficiente de X2^3
                app.X1X1X2EditField.Value = b(9);   % Coeficiente de X1^2*X2
                app.X1X2X2EditField.Value = b(10);  % Coeficiente de X1*X2^2

                
            elseif num_factors == 3
                app.boEditField.Value = b(1);          % Intercepto (b0)
                app.X1EditField.Value = b(2);          % Coeficiente de X1
                app.X2EditField.Value = b(3);          % Coeficiente de X2
                app.X3EditField.Value = b(4);          % Coeficiente de X3
                app.X1X1EditField.Value = b(5);        % Coeficiente de X1^2
                app.X2X2EditField.Value = b(6);        % Coeficiente de X2^2
                app.X3X3EditField.Value = b(7);        % Coeficiente de X3^2
                app.X1X2EditField.Value = b(8);        % Coeficiente de X1*X2
                app.X1X3EditField.Value = b(9);        % Coeficiente de X1*X3
                app.X2X3EditField.Value = b(10);       % Coeficiente de X2*X3
                app.X1X1X1EditField.Value = b(11);     % Coeficiente de X1^3
                app.X2X2X2EditField.Value = b(12);     % Coeficiente de X2^3
                app.X3X3X3EditField.Value = b(13);     % Coeficiente de X3^3
                app.X1X1X2EditField.Value = b(14);     % Coeficiente de X1^2*X2
                app.X1X1X3EditField.Value = b(15);     % Coeficiente de X1^2*X3
                app.X1X2X2EditField.Value = b(16);     % Coeficiente de X1*X2^2
                app.X1X3X3EditField.Value = b(17);     % Coeficiente de X1*X3^2
                app.X2X2X3EditField.Value = b(18);     % Coeficiente de X2^2*X3
                app.X2X3X3EditField.Value = b(19);     % Coeficiente de X2*X3^2
                app.X1X2X3EditField.Value = b(20);     % Coeficiente de X1*X2*X3

            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CriarModeloButton
        function CriarModeloButtonPushed2(app, event)
        
        app.PlotarButton.Visible = 'on';    

        % Obter as condições iniciais da tabela
       

        % Obter os limites inferiores e superiores da tabela
        lower_bounds = app.BoundsTable.Data(1, :);  % Limites inferiores
        upper_bounds = app.BoundsTable.Data(2, :);  % Limites superiores
   

        % Obter os valores da tabela (X1, X2, [X3]) e Y
        data = app.InputTable.Data;

        selectedButton = app.FactorSelectionGroup.SelectedObject;
        

        if selectedButton == app.TwoFactorsRadioButton
            X1 = data(:, 1); % Coluna X1
            X2 = data(:, 2); % Coluna X2
            Y = data(:, 3);  % Coluna Y
        
            % Chamar a função para 2 fatores
            
            [b, df, F_test, residuals, b_raw, R] = gerarModelo2f(app, X1, X2, Y);
            app.B = b;
                
            % Mostrar coeficientes
            updateModelCoefficientsTable(app, b, 2);
                

            % Mostra valores para o teste F
            app.AnovaLabel.Visible = 'on';
            app.FTestTable.Visible = 'on';
            app.FTestTable.ColumnName = {'Valor F', 'Valor Crítico'};
            app.FTestTable.RowName = {'Modelo', 'X1', 'X2', 'X1X1', 'X2X2', 'X1X2', 'X1X1X1', ...
                'X2X2X2', 'X1X1X2', 'X1X2X2'};
            data = [b zeros(10,1)]; i = 1; j = 1;
            while i <= 10
                if data(i,1) == 0
                    i = i + 1;
                else
                    data(i,1) = b_raw(j, 2);
                    data(i,2) = finv(0.95, 1, df(2));
                    i = i + 1;
                    j = j + 1;
                end
            end
            data = [F_test finv(0.95, df(1), df(2)); data];
            data(2,:) = [];
            app.FTestTable.Data = data;

            % Mostrar valores na tabela R
            app.RTable.RowName = {'Resíduos', 'Total', 'R^2'};
            app.RTable.ColumnName = {};
            app.RTable.Data = R(:);


            % Plota resíduos
            plotResiduals(app, residuals);


        elseif selectedButton == app.ThreeFactorsRadioButton
            X1 = data(:, 1); % Coluna X1
            X2 = data(:, 2); % Coluna X2
            X3 = data(:, 3); % Coluna X3
            Y = data(:, 4);  % Coluna Y
        
            % Chamar a função para 3 fatores
            
            [b, df, F_test, residuals, b_raw, R] = gerarModelo3f(app, X1, X2, X3, Y);
            app.B = b;
                
            % Mostrar coeficientes
            updateModelCoefficientsTable(app, b, 3);
                

            % Mostra valores para o teste F
            app.AnovaLabel.Visible = 'on';
            app.FTestTable.Visible = 'on';
            app.FTestTable.ColumnName = {'Valor F', 'Valor Crítico'};
            app.FTestTable.RowName = {
            'Modelo', ...
            'X1', 'X2', 'X3', ...                   % Termos lineares
            'X1X1', 'X2X2', 'X3X3', ...             % Termos quadráticos
            'X1X2', 'X1X3', 'X2X3', ...             % Interações de segunda ordem
            'X1X1X1', 'X2X2X2', 'X3X3X3', ...       % Termos cúbicos
            'X1X1X2', 'X1X1X3', 'X1X2X2', ...       % Interações cúbicas cruzadas 
            'X1X3X3', 'X2X2X3', 'X2X3X3', ...       % Continuação dos cruzados cúbicos
            'X1X2X3'                                % Interação cúbica de todos os três fatores
            };
            data = [b zeros(20,1)]; i = 1; j = 1;
            while i <= 20
                if data(i,1) == 0
                    i = i + 1;
                else
                    data(i,1) = b_raw(j, 2);
                    data(i,2) = finv(0.95, 1, df(2));
                    i = i + 1;
                    j = j + 1;
                end
            end
            data = [F_test finv(0.95, df(1), df(2)); data];
            data(2,:) = [];
            app.FTestTable.Data = data;

            % Mostrar valores na tabela R
            app.RTable.RowName = {'Resíduos', 'Total', 'R^2'};
            app.RTable.ColumnName = {};
            app.RTable.Data = R(:);

            % Plota resíduos
            plotResiduals(app, residuals);

        end
        end

        % Button pushed function: GerarPlanejamentoButton
        function GerarPlanejamentoButtonPushed(app, event)
            app.PlanTable.Visible = 'on';
            
            % Criar uma nova janela para o gráfico
            graficoFig = uifigure('Name', 'Planejamento', 'Position', [100, 100, 600, 500]);

            % Adicionar um UIAxes na nova janela
            app.Plan = uiaxes(graficoFig);
            app.Plan.Position = [50, 50, 500, 400];  % Ajustar a posição e tamanho do gráfico


            % Obter os vertices e superiores da tabela
            vertices = app.BoundsTable.Data;
          
            selectedButton = app.FactorSelectionGroup.SelectedObject;
            
            if selectedButton == app.TwoFactorsRadioButton       
                STR = planejarSTR(app, vertices);
                app.PlanTable.ColumnName = {'X1', 'X2'};
                app.PlanTable.Data = STR;
            elseif selectedButton == app.ThreeFactorsRadioButton
                FCC = planejarFCC(app, vertices);
                app.PlanTable.ColumnName = {'X1', 'X2'};
                app.PlanTable.Data = FCC;
            end
        
        end

        % Selection changed function: FactorSelectionGroup
        function FactorSelectionGroupSelectionChanged(app, event)
            selectedButton = app.FactorSelectionGroup.SelectedObject;
                
            if selectedButton == app.TwoFactorsRadioButton
                % Configurar a tabela para 2 fatores (X1 e X2) + Y
                app.InputTable.ColumnName = {'X1', 'X2', 'Y'};
                app.InputTable.Data = zeros(11, 3); % Ajustar número de colunas para 3
                
                
                app.BoundsTable.ColumnName = {'X1', 'X2'};
                app.BoundsTable.RowName = {'A', 'B', 'C', 'D'};
                app.BoundsTable.Data = zeros(4, 2); 
                app.X3CheckBox.Value = 0; app.X3CheckBox.Enable = 'off'; app.X3EditField.Enable = 'off';
                app.X3X3CheckBox.Value = 0; app.X3X3CheckBox.Enable = 'off'; app.X3X3EditField.Enable = 'off';
                app.X1X3CheckBox.Value = 0; app.X1X3CheckBox.Enable = 'off'; app.X1X3EditField.Enable = 'off';
                app.X2X3CheckBox.Value = 0; app.X2X3CheckBox.Enable = 'off'; app.X2X3EditField.Enable = 'off';
                app.X1X1X3CheckBox.Value = 0; app.X1X1X3CheckBox.Enable = 'off'; app.X1X1X3EditField.Enable = 'off';
                app.X1X3X3CheckBox.Value = 0; app.X1X3X3CheckBox.Enable = 'off'; app.X1X3X3EditField.Enable = 'off';
                app.X2X2X3CheckBox.Value = 0; app.X2X2X3CheckBox.Enable = 'off'; app.X2X2X3EditField.Enable = 'off';
                app.X2X3X3CheckBox.Value = 0; app.X2X3X3CheckBox.Enable = 'off'; app.X2X3X3EditField.Enable = 'off';
                app.X1X2X3CheckBox.Value = 0; app.X1X2X3CheckBox.Enable = 'off'; app.X1X2X3EditField.Enable = 'off';
                app.X3X3X3CheckBox.Value = 0; app.X3X3X3CheckBox.Enable = 'off'; app.X3X3X3EditField.Enable = 'off';

            elseif selectedButton == app.ThreeFactorsRadioButton
                % Configurar a tabela para 3 fatores (X1, X2 e X3) + Y
                app.InputTable.ColumnName = {'X1', 'X2', 'X3', 'Y'};
                app.InputTable.Data = zeros(17, 4); % Ajustar número de colunas para 4
                
                % Configurar a tabela de limites para 3 fatores (X1, X2 e X3)
                app.BoundsTable.ColumnName = {'X1', 'X2', 'X3'};
                app.BoundsTable.RowName = {'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H'};
                app.BoundsTable.Data = zeros(8, 3); 

                app.X3CheckBox.Value = 1; app.X3CheckBox.Enable = 'on'; app.X3EditField.Enable = 'on';
                app.X3X3CheckBox.Value = 0; app.X3X3CheckBox.Enable = 'on'; app.X3X3EditField.Enable = 'on';
                app.X1X3CheckBox.Value = 0; app.X1X3CheckBox.Enable = 'on'; app.X1X3EditField.Enable = 'on';
                app.X2X3CheckBox.Value = 0; app.X2X3CheckBox.Enable = 'on'; app.X2X3EditField.Enable = 'on';
                app.X1X1X3CheckBox.Value = 0; app.X1X1X3CheckBox.Enable = 'on'; app.X1X1X3EditField.Enable = 'on';
                app.X1X3X3CheckBox.Value = 0; app.X1X3X3CheckBox.Enable = 'on'; app.X1X3X3EditField.Enable = 'on';
                app.X2X2X3CheckBox.Value = 0; app.X2X2X3CheckBox.Enable = 'on'; app.X2X2X3EditField.Enable = 'on';
                app.X2X3X3CheckBox.Value = 0; app.X2X3X3CheckBox.Enable = 'on'; app.X2X3X3EditField.Enable = 'on';
                app.X1X2X3CheckBox.Value = 0; app.X1X2X3CheckBox.Enable = 'on'; app.X1X2X3EditField.Enable = 'on';
                app.X3X3X3CheckBox.Value = 0; app.X3X3X3CheckBox.Enable = 'on'; app.X3X3X3EditField.Enable = 'on';

            end
        
       
        end

        % Value changed function: PasteTextArea
        function PasteTextAreaValueChanged(app, event)
           % Obter o texto colado no TextArea
            pastedText = app.PasteTextArea.Value;

            % Converter o cell array em uma única string
            pastedText = strjoin(pastedText, '\n');
            
            % Quebrar o texto colado em linhas e colunas
            rows = strsplit(pastedText, '\n'); % Separar por linhas
            numRows = length(rows);
            
            % Preparar a tabela de dados
            data = [];
            
            for i = 1:numRows
                % Quebrar cada linha em colunas usando separador de tabulação ou vírgula
                cols = strsplit(rows{i}, {'\t', ','});  
                
                % Converter os valores de string para número
                numericCols = str2double(cols);
                
                % Adicionar os valores à tabela de dados
                data = [data; numericCols];
            end
            
            % Verificar o tamanho dos dados e ajustar a tabela de inputs
            if size(data, 2) == 2
                app.InputTable.ColumnName = {'X1', 'X2'};
            elseif size(data, 2) == 3
                app.InputTable.ColumnName = {'X1', 'X2', 'X3'};
            end
            
            % Preencher a tabela de inputs com os dados processados
            app.InputTable.Data = data;
            
        end

        % Button pushed function: MinimizarButton
        function MinimizarButtonPushed(app, event)
            selectedButton = app.FactorSelectionGroup.SelectedObject;
            if selectedButton == app.TwoFactorsRadioButton
                           
                % Obter os vértices da região experimental da tabela
                vertices = app.BoundsTable.Data;
                minimize2f(app, app.B , vertices);

            elseif selectedButton == app.ThreeFactorsRadioButton
                % Obter os vértices da região experimental da tabela
                vertices = app.BoundsTable.Data;
                minimize3f(app, app.B , vertices);

            end

            app.PlotarcompontoButton.Visible = 'on';
        end

        % Button pushed function: MaximizarButton
        function MaximizarButtonPushed(app, event)
            selectedButton = app.FactorSelectionGroup.SelectedObject;
            if selectedButton == app.TwoFactorsRadioButton
                % Obter os vértices da região experimental da tabela
                vertices = app.BoundsTable.Data;
                maximize2f(app, app.B , vertices);

            else if selectedButton == app.ThreeFactorsRadioButton
                % Obter os vértices da região experimental da tabela
                vertices = app.BoundsTable.Data;
                maximize3f(app, app.B , vertices);
            end
            end

            app.PlotarcompontoButton.Visible = 'on';
        end

        % Button pushed function: MaiorInclinaoButton
        function MaiorInclinaoButtonPushed(app, event)
        try    
            % Obter os coeficientes do modelo linear
            b = app.B;
        
            % Definir a função de superfície
            model_func = @(X1, X2) (b(1) + b(2) * X1 + b(3) * X2);
        
            % Obter o ponto inicial (IC) da tabela de condições iniciais
            IC = app.InitialConditionsTable.Data;
        
            % Obter os limites inferiores e superiores da tabela
            vertices = app.BoundsTable.Data;
            lower_bounds = [min(vertices(:, 1)), min(vertices(:, 2))]; % Limites inferiores
            upper_bounds = [max(vertices(:, 1)), max(vertices(:, 2))]; % Limites superiores
        
            % Definir o gradiente como [b1; b2] (direção de maior inclinação)
            grad = [b(2); b(3)];
        
            % Normalizar o gradiente para obter a direção unitária
            grad_direction = grad / norm(grad);
        
            % Definir o comprimento da linha de inclinação com base nos limites
            line_length = min(abs(upper_bounds - IC), abs(IC - lower_bounds)) * 0.8;
        
            % Obter o número de pontos da linha a partir do Spinner
            num_points = app.NmerodePontosSpinner.Value;
        
            % Calcular incrementos ao longo da linha de maior inclinação
            X1_line = linspace(IC(1) - line_length(1) * grad_direction(1), ...
                               IC(1) + line_length(1) * grad_direction(1), num_points);
            X2_line = linspace(IC(2) - line_length(2) * grad_direction(2), ...
                               IC(2) + line_length(2) * grad_direction(2), num_points);
            Z_line = model_func(X1_line, X2_line);
        
            % Plotar os pontos ao longo da linha de maior inclinação
            hold(app.Grafico, 'on');
            plot3(app.Grafico, X1_line, X2_line, Z_line, 'ko', 'MarkerSize', 8, 'LineWidth', 2);
            plot3(app.Grafico, IC(1), IC(2), model_func(IC(1),IC(2)), 'ro', 'MarkerSize', 10, 'LineWidth', 2);
            hold(app.Grafico, 'off'); 
        catch ME
             uialert(app.UIFigure, ['Erro ao exibir maior inclinação: ', ME.message], ...
                        'Erro de Exibição');
        end
        
        end

        % Button pushed function: PlotarButton
        function PlotarButtonPushed(app, event)
            
            % Criar uma nova janela para o gráfico
            graficoFig = uifigure('Name', 'Gráfico de Superfície', 'Position', [100, 100, 600, 500]);

            % Adicionar um UIAxes na nova janela
            app.Grafico = uiaxes(graficoFig);
            app.Grafico.Position = [50, 50, 500, 400];  % Ajustar a posição e tamanho do gráfico
            
            b = app.B;

            % Obter os limites inferiores e superiores da tabela
            vertices = app.BoundsTable.Data;
            lower_bounds = [min(vertices(:, 1)), min(vertices(:, 2))]; % Limites inferiores
            upper_bounds = [max(vertices(:, 1)), max(vertices(:, 2))]; % Limites superiores
           
            
            selectedButton = app.FactorSelectionGroup.SelectedObject;
            
            if selectedButton == app.TwoFactorsRadioButton       
                plotModelSurface2f(app, b, lower_bounds, upper_bounds);
            elseif selectedButton == app.ThreeFactorsRadioButton
                plotModelSurface3f(app, b, lower_bounds, upper_bounds);
            end


        end

        % Button pushed function: PlotarcompontoButton
        function PlotarcompontoButtonPushed(app, event)
           % Criar uma nova janela para o gráfico
            graficoFig = uifigure('Name', 'Gráfico de Superfície', 'Position', [100, 100, 600, 500]);

            % Adicionar um UIAxes na nova janela
            app.Grafico = uiaxes(graficoFig);
            app.Grafico.Position = [50, 50, 500, 400];  % Ajustar a posição e tamanho do gráfico
            
            b = app.B;

            % Obter os limites inferiores e superiores da tabela
            vertices = app.BoundsTable.Data;
            lower_bounds = [min(vertices(:, 1)), min(vertices(:, 2))]; % Limites inferiores
            upper_bounds = [max(vertices(:, 1)), max(vertices(:, 2))]; % Limites superiores
            
            selectedButton = app.FactorSelectionGroup.SelectedObject;
            
            if selectedButton == app.TwoFactorsRadioButton       
                plotModelSurface2f(app, b, lower_bounds, upper_bounds);
                hold(app.Grafico, 'on');
                plot3(app.Grafico, app.OptP(1), app.OptP(2), app.OptY, 'kp', 'MarkerSize', 10, 'LineWidth', 4);
            elseif selectedButton == app.ThreeFactorsRadioButton
                plotModelSurface3f(app, b, lower_bounds, upper_bounds);
                hold(app.Grafico, 'on');
                plot3(app.Grafico, app.OptP(1), app.OptP(2), app.OptP(3), 'kp', 'MarkerSize', 10, 'LineWidth', 4);
            end
            hold(app.Grafico,'off');
        end

        % Button pushed function: ImportarPlanilhaButton
        function ImportarPlanilhaButtonPushed(app, event)
            importarPlanilha(app);
        end

        % Button pushed function: Importar2Button
        function Importar2ButtonPushed(app, event)
            importarBounds(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.graficoResiduos = uiaxes(app.UIFigure);
            title(app.graficoResiduos, 'Resíduos')
            xlabel(app.graficoResiduos, 'Y')
            ylabel(app.graficoResiduos, 'Resíduos')
            zlabel(app.graficoResiduos, 'Z')
            app.CriarModeloButton = uibutton(app.UIFigure, 'push');
            app.CriarModeloButton.ButtonPushedFcn = createCallbackFcn(app, @CriarModeloButtonPushed2, true);
            app.CriarModeloButton.Text = 'Criar Modelo';
            app.GerarPlanejamentoButton = uibutton(app.UIFigure, 'push');
            app.GerarPlanejamentoButton.ButtonPushedFcn = createCallbackFcn(app, @GerarPlanejamentoButtonPushed, true);
            app.GerarPlanejamentoButton.Text = 'Gerar Planejamento';
            app.InputTable = uitable(app.UIFigure);
            app.InputTable.ColumnName = '';
            app.InputTable.RowName = {};
            app.InputTable.ColumnEditable = true;
            app.TabeladeinputsLabel = uilabel(app.UIFigure);
            app.TabeladeinputsLabel.Text = 'Tabela de inputs ';
            app.FactorSelectionGroup = uibuttongroup(app.UIFigure);
            app.FactorSelectionGroup.SelectionChangedFcn = createCallbackFcn(app, @FactorSelectionGroupSelectionChanged, true);
            app.FactorSelectionGroup.Title = 'Fatores';
            app.TwoFactorsRadioButton = uiradiobutton(app.FactorSelectionGroup);
            app.TwoFactorsRadioButton.Text = '2 fatores';
            app.TwoFactorsRadioButton.Value = true;
            app.ThreeFactorsRadioButton = uiradiobutton(app.FactorSelectionGroup);
            app.ThreeFactorsRadioButton.Text = '3 fatores';
            app.BoundsTable = uitable(app.UIFigure);
            app.BoundsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.BoundsTable.RowName = {};
            app.BoundsTable.ColumnEditable = true;
            app.InitialConditionsTable = uitable(app.UIFigure);
            app.InitialConditionsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.InitialConditionsTable.RowName = {};
            app.InitialConditionsTable.ColumnEditable = true;
            app.InitialConditionsTable.Visible = 'off';
            app.CoeficientesdoModeloLabel = uilabel(app.UIFigure);
            app.CoeficientesdoModeloLabel.Text = 'Coeficientes do Modelo';
            app.PasteTextArea = uitextarea(app.UIFigure);
            app.PasteTextArea.ValueChangedFcn = createCallbackFcn(app, @PasteTextAreaValueChanged, true);
            app.FTestTable = uitable(app.UIFigure);
            app.FTestTable.ColumnName = '';
            app.FTestTable.RowName = {};
            app.FTestTable.Tooltip = {'a'};
            app.MaximizarButton = uibutton(app.UIFigure, 'push');
            app.MaximizarButton.ButtonPushedFcn = createCallbackFcn(app, @MaximizarButtonPushed, true);
            app.MaximizarButton.Text = 'Maximizar';
            app.MinimizarButton = uibutton(app.UIFigure, 'push');
            app.MinimizarButton.ButtonPushedFcn = createCallbackFcn(app, @MinimizarButtonPushed, true);
            app.MinimizarButton.Text = 'Minimizar';
            app.MaiorInclinaoButton = uibutton(app.UIFigure, 'push');
            app.MaiorInclinaoButton.ButtonPushedFcn = createCallbackFcn(app, @MaiorInclinaoButtonPushed, true);
            app.MaiorInclinaoButton.Visible = 'off';
            app.MaiorInclinaoButton.Text = 'Maior Inclinação';
            app.NmerodePontosSpinner = uispinner(app.UIFigure);
            app.NmerodePontosSpinner.Limits = [5 20];
            app.NmerodePontosSpinner.RoundFractionalValues = 'on';
            app.NmerodePontosSpinner.Visible = 'off';
            app.NmerodePontosSpinner.Value = 5;
            app.PlotarButton = uibutton(app.UIFigure, 'push');
            app.PlotarButton.ButtonPushedFcn = createCallbackFcn(app, @PlotarButtonPushed, true);
            app.PlotarButton.Visible = 'off';
            app.PlotarButton.Text = 'Plotar';
            app.AnovaLabel = uilabel(app.UIFigure);
            app.AnovaLabel.Visible = 'off';
            app.AnovaLabel.Text = 'Anova:';
            app.optimizeTable = uitable(app.UIFigure);
            app.optimizeTable.ColumnName = {''; ''; ''; ''};
            app.optimizeTable.RowName = {};
            app.PontotimoLabel = uilabel(app.UIFigure);
            app.PontotimoLabel.Text = 'Ponto ótimo:';
            app.boEditFieldLabel = uilabel(app.UIFigure);
            app.boEditFieldLabel.Text = 'bo';
            app.boEditField = uieditfield(app.UIFigure, 'numeric');
            app.X1EditFieldLabel = uilabel(app.UIFigure);
            app.X1EditFieldLabel.Text = 'X1';
            app.X1EditField = uieditfield(app.UIFigure, 'numeric');
            app.X2EditFieldLabel = uilabel(app.UIFigure);
            app.X2EditFieldLabel.Text = 'X2';
            app.X2EditField = uieditfield(app.UIFigure, 'numeric');
            app.X3EditFieldLabel = uilabel(app.UIFigure);
            app.X3EditFieldLabel.Text = 'X3';
            app.X3EditField = uieditfield(app.UIFigure, 'numeric');
            app.X12EditFieldLabel = uilabel(app.UIFigure);
            app.X12EditFieldLabel.Text = 'X1^2';
            app.X1X1EditField = uieditfield(app.UIFigure, 'numeric');
            app.X22EditFieldLabel = uilabel(app.UIFigure);
            app.X22EditFieldLabel.Text = 'X2^2';
            app.X2X2EditField = uieditfield(app.UIFigure, 'numeric');
            app.X32EditFieldLabel = uilabel(app.UIFigure);
            app.X32EditFieldLabel.Text = 'X3^2';
            app.X3X3EditField = uieditfield(app.UIFigure, 'numeric');
            app.X1X2EditFieldLabel = uilabel(app.UIFigure);
            app.X1X2EditFieldLabel.Text = 'X1*X2';
            app.X1X2EditField = uieditfield(app.UIFigure, 'numeric');
            app.X1X3EditFieldLabel = uilabel(app.UIFigure);
            app.X1X3EditFieldLabel.Text = 'X1*X3';
            app.X1X3EditField = uieditfield(app.UIFigure, 'numeric');
            app.X2X3EditFieldLabel = uilabel(app.UIFigure);
            app.X2X3EditFieldLabel.Text = 'X2*X3';
            app.X2X3EditField = uieditfield(app.UIFigure, 'numeric');
            app.boCheckBox = uicheckbox(app.UIFigure);
            app.boCheckBox.Text = '';
            app.boCheckBox.Value = true;
            app.X1CheckBox = uicheckbox(app.UIFigure);
            app.X1CheckBox.Text = '';
            app.X1CheckBox.Value = true;
            app.X2CheckBox = uicheckbox(app.UIFigure);
            app.X2CheckBox.Text = '';
            app.X2CheckBox.Value = true;
            app.X3CheckBox = uicheckbox(app.UIFigure);
            app.X3CheckBox.Text = '';
            app.X2X2CheckBox = uicheckbox(app.UIFigure);
            app.X2X2CheckBox.Text = '';
            app.X3X3CheckBox = uicheckbox(app.UIFigure);
            app.X3X3CheckBox.Text = '';
            app.X1X1CheckBox = uicheckbox(app.UIFigure);
            app.X1X1CheckBox.Text = '';
            app.X1X2CheckBox = uicheckbox(app.UIFigure);
            app.X1X2CheckBox.Text = '';
            app.X1X3CheckBox = uicheckbox(app.UIFigure);
            app.X1X3CheckBox.Text = '';
            app.X2X3CheckBox = uicheckbox(app.UIFigure);
            app.X2X3CheckBox.Text = '';
            app.X1X1X1CheckBox = uicheckbox(app.UIFigure);
            app.X1X1X1CheckBox.Text = '';
            app.X2X2X2CheckBox = uicheckbox(app.UIFigure);
            app.X2X2X2CheckBox.Text = '';
            app.X3X3X3CheckBox = uicheckbox(app.UIFigure);
            app.X3X3X3CheckBox.Text = '';
            app.X13EditFieldLabel = uilabel(app.UIFigure);
            app.X13EditFieldLabel.Text = 'X1^3';
            app.X1X1X1EditField = uieditfield(app.UIFigure, 'numeric');
            app.X23EditFieldLabel = uilabel(app.UIFigure);
            app.X23EditFieldLabel.Text = 'X2^3';
            app.X2X2X2EditField = uieditfield(app.UIFigure, 'numeric');
            app.X33EditFieldLabel = uilabel(app.UIFigure);
            app.X33EditFieldLabel.Text = 'X3^3';
            app.X3X3X3EditField = uieditfield(app.UIFigure, 'numeric');
            app.X1X1X3CheckBox = uicheckbox(app.UIFigure);
            app.X1X1X3CheckBox.Text = '';
            app.X1X2X2CheckBox = uicheckbox(app.UIFigure);
            app.X1X2X2CheckBox.Text = '';
            app.X1X1X2CheckBox = uicheckbox(app.UIFigure);
            app.X1X1X2CheckBox.Text = '';
            app.X1X3X3CheckBox = uicheckbox(app.UIFigure);
            app.X1X3X3CheckBox.Text = '';
            app.X2X2X3CheckBox = uicheckbox(app.UIFigure);
            app.X2X2X3CheckBox.Text = '';
            app.X2X3X3CheckBox = uicheckbox(app.UIFigure);
            app.X2X3X3CheckBox.Text = '';
            app.X12X2Label = uilabel(app.UIFigure);
            app.X12X2Label.Text = '(X1^2)X2';
            app.X1X1X2EditField = uieditfield(app.UIFigure, 'numeric');
            app.X12X3Label = uilabel(app.UIFigure);
            app.X12X3Label.Text = '(X1^2)X3';
            app.X1X1X3EditField = uieditfield(app.UIFigure, 'numeric');
            app.X1X22Label = uilabel(app.UIFigure);
            app.X1X22Label.Text = 'X1*X2^2';
            app.X1X2X2EditField = uieditfield(app.UIFigure, 'numeric');
            app.X1X32EditFieldLabel = uilabel(app.UIFigure);
            app.X1X32EditFieldLabel.Text = 'X1*X3^2';
            app.X1X3X3EditField = uieditfield(app.UIFigure, 'numeric');
            app.X22X3EditFieldLabel = uilabel(app.UIFigure);
            app.X22X3EditFieldLabel.Text = '(X2^2)X3';
            app.X2X2X3EditField = uieditfield(app.UIFigure, 'numeric');
            app.X2X32EditFieldLabel = uilabel(app.UIFigure);
            app.X2X32EditFieldLabel.Text = 'X2*X3^2';
            app.X2X3X3EditField = uieditfield(app.UIFigure, 'numeric');
            app.X1X2X3CheckBox = uicheckbox(app.UIFigure);
            app.X1X2X3CheckBox.Text = 'X1*X2*X3';
            app.X1X2X3EditField = uieditfield(app.UIFigure, 'numeric');
            app.PlotarcompontoButton = uibutton(app.UIFigure, 'push');
            app.PlotarcompontoButton.ButtonPushedFcn = createCallbackFcn(app, @PlotarcompontoButtonPushed, true);
            app.PlotarcompontoButton.Visible = 'off';
            app.PlotarcompontoButton.Text = 'Plotar com ponto ótimo';
            app.RTable = uitable(app.UIFigure);
            app.RTable.ColumnName = {''; ''; ''; ''};
            app.RTable.RowName = {};
            app.PlanTable = uitable(app.UIFigure);
            app.PlanTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.PlanTable.RowName = {};
            app.PlanTable.Visible = 'off';
            app.ImportarPlanilhaButton = uibutton(app.UIFigure, 'push');
            app.ImportarPlanilhaButton.ButtonPushedFcn = createCallbackFcn(app, @ImportarPlanilhaButtonPushed, true);
            app.ImportarPlanilhaButton.Text = 'Importar Planilha';
            app.Importar2Button = uibutton(app.UIFigure, 'push');
            app.Importar2Button.ButtonPushedFcn = createCallbackFcn(app, @Importar2ButtonPushed, true);
            app.Importar2Button.Icon = fullfile(pathToMLAPP, '324104.png');
            app.Importar2Button.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Osiris

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end